classdef winTracking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Grid               matlab.ui.container.GridLayout
        CancelButton       matlab.ui.control.Button
        ErrorLabel         matlab.ui.control.Label
        Panel              matlab.ui.container.Panel
        PolarizationLabel  matlab.ui.control.Label
        ElevationLabel     matlab.ui.control.Label
        AzimuthLabel       matlab.ui.control.Label
        Polarization       matlab.ui.control.Gauge
        Elevation          matlab.ui.control.NinetyDegreeGauge
        Azimuth            matlab.ui.control.Gauge
        PositionGrid       matlab.ui.container.GridLayout
        finalPosValue      matlab.ui.control.Label
        finalPosLabel      matlab.ui.control.Label
        initialPosValue    matlab.ui.control.Label
        initialPosLabel    matlab.ui.control.Label
        antennaName        matlab.ui.control.Label
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        CallingApp
        RootFolder
        EMSatObj

        UpdateStatus = true
    end
    

    methods (Access = private)
        %-----------------------------------------------------------------%
        function gaugeStartup(app, targetPos, antennaPos)

            set(app.Azimuth,      'Value', antennaPos.Azimuth, ...
                                  'ScaleColors', [1 1 0],      ...
                                  'ScaleColorLimits', sort([targetPos.Azimuth, antennaPos.Azimuth]))

            set(app.Elevation,    'Value', antennaPos.Elevation, ...
                                  'ScaleColors', [1 1 0],        ...
                                  'ScaleColorLimits', sort([targetPos.Elevation, antennaPos.Elevation]))

            set(app.Polarization, 'Value', antennaPos.Polarization, ...
                                  'ScaleColors', [1 1 0],           ...
                                  'ScaleColorLimits', sort([targetPos.Polarization, antennaPos.Polarization]))
        end


        %-----------------------------------------------------------------%
        function setNewPosition(app, targetPos)

            while true
                msgError = app.EMSatObj.AntennaPositionSET(targetPos);

                if ~isempty(msgError)
                    msg = sprintf('<font style="font-size:11;">%s\n\nDeseja tentar mais uma vez?</font>', msgError);    
                    
                    selection = uiconfirm(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html', 'Options', {'Sim', 'Não'}, 'DefaultOption', 1, 'CancelOption', 2, 'Icon', 'question');                
                    if selection == "Sim"
                        continue
                    else
                        error(msgError)
                    end
                end

                break
            end
        end


        %-----------------------------------------------------------------%
        function updatePosition(app, targetPos, antennaPos)

            initialPos = updateReference(app, antennaPos);

            trackTic = tic;
            while app.UpdateStatus
                pause(1)

                if toc(trackTic) > 10
                    if isequal(initialPos, antennaPos)
                        layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', 'Não alterado o apontamento da antena mesmo após decorridos 10 segundos...');
                    else
                        initialPos = updateReference(app, antennaPos);
                    end
                    trackTic = tic;
                end

                [newAntennaPos, errorMsg] = app.EMSatObj.AntennaPositionGET(targetPos.Name);
                if ~isempty(errorMsg)
                    app.ErrorLabel.Text = errorMsg;
                    continue
                end
                antennaPos = newAntennaPos;

                app.Azimuth.Value      = antennaPos.Azimuth;
                app.Elevation.Value    = antennaPos.Elevation;
                app.Polarization.Value = antennaPos.Polarization;
                app.ErrorLabel.Text    = '';

                if abs(targetPos.Azimuth      - antennaPos.Azimuth)      < 0.2 && ...
                   abs(targetPos.Elevation    - antennaPos.Elevation)    < 0.2 && ...
                   abs(targetPos.Polarization - antennaPos.Polarization) < 0.2
                    closeFcn(app)
                end
            end        
        end


        %-----------------------------------------------------------------%
        function initialPos = updateReference(app, antennaPos)

            initialPos = struct('Azimuth',      antennaPos.Azimuth,   ...
                                'Elevation',    antennaPos.Elevation, ...
                                'Polarization', antennaPos.Polarization);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, targetPos, antennaPos)
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            fcn.winPosition(app)

            app.EMSatObj   = app.CallingApp.EMSatObj;

            gaugeStartup(app, targetPos, antennaPos)
            setNewPosition(app, targetPos)
            updatePosition(app, targetPos, antennaPos)

        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            delete(app)
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            
            app.UpdateStatus = false;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid = uigridlayout(app.UIFigure);
            app.antennaName = uilabel(app.Grid);
            app.antennaName.Text = 'MCL-1';
            app.PositionGrid = uigridlayout(app.Grid);
            app.initialPosLabel = uilabel(app.PositionGrid);
            app.initialPosLabel.Text = 'Posição inicial:';
            app.initialPosValue = uilabel(app.PositionGrid);
            app.initialPosValue.Text = '(120.123º, 120.123º, 120.123º)';
            app.finalPosLabel = uilabel(app.PositionGrid);
            app.finalPosLabel.Text = 'Posição alvo:';
            app.finalPosValue = uilabel(app.PositionGrid);
            app.finalPosValue.Text = '(120.123º, 120.123º, 120.123º)';
            app.Panel = uipanel(app.Grid);
            app.Azimuth = uigauge(app.Panel, 'circular');
            app.Azimuth.Limits = [0 360];
            app.Azimuth.MajorTicks = [0 45 90 135 180 225 270 315 360];
            app.Azimuth.MajorTickLabels = {'0', '45', '90', '135', '180', '225', '270', '315', '360'};
            app.Elevation = uigauge(app.Panel, 'ninetydegree');
            app.Elevation.Limits = [0 90];
            app.Elevation.MajorTicks = [0 22.5 45 67.5 90];
            app.Elevation.MajorTickLabels = {'0', '22.5', '45', '67.5', '90'};
            app.Elevation.MinorTicks = [0 3 6 9 12 15 18 27 30 33 36 39 42 45 48 51 54 57 60 63 72 75 78 81 84 87 90];
            app.Polarization = uigauge(app.Panel, 'circular');
            app.Polarization.Limits = [0 360];
            app.Polarization.MajorTicks = [0 45 90 135 180 225 270 315 360];
            app.Polarization.MajorTickLabels = {'0', '45', '90', '135', '180', '225', '270', '315', '360'};
            app.AzimuthLabel = uilabel(app.Panel);
            app.AzimuthLabel.Text = 'AZIMUTE';
            app.ElevationLabel = uilabel(app.Panel);
            app.ElevationLabel.Text = 'ELEVAÇÃO';
            app.PolarizationLabel = uilabel(app.Panel);
            app.PolarizationLabel.Text = 'POLARIZAÇÃO';
            app.ErrorLabel = uilabel(app.Grid);
            app.ErrorLabel.Text = '';
            app.CancelButton = uibutton(app.Grid, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Icon = 'LT_redX.png';
            app.CancelButton.Tooltip = {'Incluir tarefa'};
            app.CancelButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winTracking(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end